classdef ES3H3_Lab1_Assessment_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActivateWebcamButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        StopWebcamButton                matlab.ui.control.Button
        OpenownimagefileButton          matlab.ui.control.Button
        UIAxesSC                        matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        GaussianFilterSettingsPanel     matlab.ui.container.Panel
        GaussianBlur_1CheckBox          matlab.ui.control.CheckBox
        Gaussianstandarddeviation_1SliderLabel  matlab.ui.control.Label
        Gaussianstandarddeviation_1Slider  matlab.ui.control.Slider
        DifferenceofGaussiansCheckBox   matlab.ui.control.CheckBox
        BinaryImageCheckBox             matlab.ui.control.CheckBox
        Gaussianstandarddeviation_2SliderLabel  matlab.ui.control.Label
        Gaussianstandarddeviation_2Slider  matlab.ui.control.Slider
        GaussWindowSliderLabel          matlab.ui.control.Label
        GaussWindowSlider               matlab.ui.control.Slider
        FilterSelectPanel               matlab.ui.container.Panel
        PrewittFilterPanel              matlab.ui.container.Panel
        PrewittyCheckBox                matlab.ui.control.CheckBox
        PrewittxCheckBox                matlab.ui.control.CheckBox
        SobelFilterPanel                matlab.ui.container.Panel
        SobelyCheckBox                  matlab.ui.control.CheckBox
        SobelxCheckBox                  matlab.ui.control.CheckBox
        OtherKernelsPanel               matlab.ui.container.Panel
        KirschCheckBox                  matlab.ui.control.CheckBox
        StandardCheckBox                matlab.ui.control.CheckBox
        RobertsFilterPanel              matlab.ui.container.Panel
        RobertsxCheckBox                matlab.ui.control.CheckBox
        RobertsyCheckBox                matlab.ui.control.CheckBox
        EdgeDetectionSettingsPanel      matlab.ui.container.Panel
        EdgeDetectionCheckBox           matlab.ui.control.CheckBox
        EdgeThresholdSliderLabel        matlab.ui.control.Label
        EdgeThresholdSlider             matlab.ui.control.Slider
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SobelButton                     matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        RobertsButton                   matlab.ui.control.RadioButton
        DetectFeaturesPanel             matlab.ui.container.Panel
        DetectHarrisFeaturesCheckBox    matlab.ui.control.CheckBox
        AmountofFeaturesSliderLabel     matlab.ui.control.Label
        AmountofFeaturesSlider          matlab.ui.control.Slider
        MinimumQualitySliderLabel       matlab.ui.control.Label
        MinimumQualitySlider            matlab.ui.control.Slider
        DetectSURFFeaturesCheckBox      matlab.ui.control.CheckBox
        StopbeforeswitchingCamerasLabel  matlab.ui.control.Label
        CurrentConvolutionKernelPanel   matlab.ui.container.Panel
        kernel_a1                       matlab.ui.control.NumericEditField
        kernel_a2                       matlab.ui.control.NumericEditField
        kernel_a3                       matlab.ui.control.NumericEditField
        kernel_b1                       matlab.ui.control.NumericEditField
        kernel_b2                       matlab.ui.control.NumericEditField
        kernel_b3                       matlab.ui.control.NumericEditField
        kernel_c1                       matlab.ui.control.NumericEditField
        kernel_c2                       matlab.ui.control.NumericEditField
        kernel_c3                       matlab.ui.control.NumericEditField
        KernelDisplayeditableLabel      matlab.ui.control.Label
        ResetKernelButton               matlab.ui.control.Button
        KernelrotationdegreesLabel      matlab.ui.control.Label
        xaxisEditFieldLabel             matlab.ui.control.Label
        xaxisEditField                  matlab.ui.control.NumericEditField
        yaxisEditFieldLabel             matlab.ui.control.Label
        yaxisEditField                  matlab.ui.control.NumericEditField
        zaxisEditFieldLabel             matlab.ui.control.Label
        zaxisEditField                  matlab.ui.control.NumericEditField
        UserInputCheckBox               matlab.ui.control.CheckBox
        RotateKernelButton              matlab.ui.control.Button
        ApplyontopofcurrentkernelPanel  matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        kernel_a1_2                     matlab.ui.control.NumericEditField
        kernel_a2_2                     matlab.ui.control.NumericEditField
        kernel_a3_2                     matlab.ui.control.NumericEditField
        kernel_b1_2                     matlab.ui.control.NumericEditField
        kernel_b2_2                     matlab.ui.control.NumericEditField
        kernel_b3_2                     matlab.ui.control.NumericEditField
        kernel_c1_2                     matlab.ui.control.NumericEditField
        kernel_c2_2                     matlab.ui.control.NumericEditField
        kernel_c3_2                     matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        CameraSelectListBoxLabel        matlab.ui.control.Label
        CameraSelectListBox             matlab.ui.control.ListBox
        FilterSwitchButtonGroup         matlab.ui.container.ButtonGroup
        OnButton                        matlab.ui.control.RadioButton
        OffButton                       matlab.ui.control.RadioButton
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        UseSampleImageCheckBox          matlab.ui.control.CheckBox
        UseOwnimagevideoCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        frame % frame of the webcam
        frame_g % greyscale frame
        frame_g2
        frame_g2dog
        frame_filter_dog
        frame_current
        frame_filt % filtered image
        image_on = 0
        im_filt
        k_default = [0 0 0; 0 1 0; 0 0 0] % initial default and comparison value of the current kernel
        k_current= [0 0 0; 0 1 0; 0 0 0]
        UIAxesSC_status = 0 % status of requiring a scaled image display
        sc_req
        webcams
        k_user
        multiply_active
        k_mult
        rotation_active
        k_rot
        
        
    end
    
    methods (Access = private)
                
        function check_settings(app)
            if (app.OffButton.Value)
               delete(app.UIAxesSC);
               app.UIAxesSC_status = 0;
               imshow(app.frame, 'parent', app.UIAxes)
               if app.DetectHarrisFeaturesCheckBox.Value || app.DetectSURFFeaturesCheckBox.Value
                   app.features()
               end
            elseif (app.OnButton.Value)
                app.select_filter();


            end % end if
        end % end check_settings()

        % Applies the selected filter kernels and displays them accordingly
        function select_filter(app)
                   [k_stan, k_sobely, k_sobelx, k_prewitty, k_prewittx, k_kirsch, k_robertsx, k_robertsy] = deal(eye(3));  % assign all kernels as identity by default
               % Gaussian blur application
               if app.DifferenceofGaussiansCheckBox.Value % Difference of Gaussian, second kernel
                   app.GaussianBlur_1CheckBox.Value = 1;
                   sizeg = app.GaussWindowSlider.Value;
                   app.frame_g2dog = imgaussfilt(app.frame_g, app.Gaussianstandarddeviation_2Slider.Value, 'FilterSize', 2*ceil(2*sizeg)+1.);
%                    k_gaus2 = fspecial('gaussian',round(app.GaussianBlur_1CheckBox.Value),app.Gaussianstandarddeviation_2Slider.Value); %image kernel
%                    app.frame_g2dog = conv2(app.frame_g, k_gaus2/256);
               end
               if (app.GaussianBlur_1CheckBox.Value)  % Gaussian blur
                   sizeg = app.GaussWindowSlider.Value;
                   app.frame_g2 = imgaussfilt(app.frame_g, app.Gaussianstandarddeviation_1Slider.Value, 'FilterSize', 2*ceil(2*sizeg)+1); 
%                    k_gaus = fspecial('gaussian',round(app.GaussianBlur_1CheckBox.Value),app.Gaussianstandarddeviation_1Slider.Value); %image kernel
%                    app.frame_g2 = conv2(app.frame_g, k_gaus/256);
               else 
                   app.frame_g2 = app.frame_g;
               end
               
               % Kernel creation
               if (app.StandardCheckBox.Value)   % Apply Standard Filter
                    k_stan = [0 0 0;1 0 -1;0 0 0];
               end
               if (app.KirschCheckBox.Value)  % Apply Kirsch filter
                    k_kirsch = [0 -1 0; -1 5 -1; 0 1 0];
               end
               if (app.SobelxCheckBox.Value)  % Apply Sobel filter - along x
                    k_sobelx = [1 0 -1; 2 0 -2; 1 0 -1];
                    im_sobelx = conv2(app.frame_g2, k_sobelx, 'same');
               end
               if (app.SobelyCheckBox.Value)  % Apply Sobel filter - along y
                    k_sobely = [1 2 1; 0 0 0; -1 -2 -1];
                    im_sobely = conv2(app.frame_g2, k_sobely, 'same');
               end
               if (app.PrewittyCheckBox.Value)  % Apply Prewitt filter - along y
                    k_prewitty = [-1 -1 -1; 0 0 0; 1 1 1];
                    im_prewitty = conv2(app.frame_g2, k_prewitty, 'same');
               end
               if (app.PrewittxCheckBox.Value)  % Apply Prewitt filter - along x
                    k_prewittx = [-1 0 1; -1 0 1; -1 0 1];
                    im_prewittx = conv2(app.frame_g2, k_prewittx, 'same');
               end
               if (app.RobertsyCheckBox.Value)  % Apply Roberts filter - along y
                    k_robertsy = [0 1 0; -1 0 0; 0 0 0];
                    im_robertsy = conv2(app.frame_g2, k_robertsy, 'same');
               end
               if (app.RobertsxCheckBox.Value)  % Apply Roberts filter - along x
                    k_robertsx = [1 0 0; 0 -1 0; 0 0 0];
                    im_robertsx = conv2(app.frame_g2, k_robertsx, 'same');
               end
               
               k = k_stan * k_sobely * k_sobelx * k_prewitty * k_prewittx * k_kirsch * k_robertsx * k_robertsy; 
               
               
               if isequal(k,eye(3))  % if no filters applied, apply the default kernel
                    k = app.k_default;
               end
               if app.UserInputCheckBox.Value || (~(app.StandardCheckBox.Value) && ~(app.SobelxCheckBox.Value) && ~(app.SobelyCheckBox.Value) && ~(app.PrewittxCheckBox.Value) && ~(app.PrewittyCheckBox.Value) && ~(app.RobertsxCheckBox.Value) && ~(app.RobertsyCheckBox.Value) && ~(app.KirschCheckBox.Value) && ~(app.GaussianBlur_1CheckBox.Value))
                    k = app.k_user;
                    app.sc_req = 1;
               end
               if app.rotation_active
                   k = k * app.k_rot;
                   app.sc_req = 1;
                   app.rotation_active = 0;
               end
               if app.multiply_active == 1
                   [app.SobelxCheckBox.Value, app.SobelyCheckBox.Value, app.PrewittyCheckBox.Value, app.PrewittxCheckBox.Value, app.RobertsxCheckBox.Value, app.RobertsyCheckBox.Value] = deal(0);
                   k = conv2(k,app.k_mult);
                   app.sc_req = 1;
               end
               if ~(isequal(app.k_current,k))   % if a value has changed, display it
                    app.disp_k(k);
               end
           
            %% Display Filters
            
            
           % Generate compound filter image %
           app.im_filt = conv2(app.frame_g2, k);
           app.frame_current = app.im_filt;
          
            if app.UserInputCheckBox.Value
               % top priority
               app.sc_req = 1;
            elseif app.GaussianBlur_1CheckBox.Value && ~(app.StandardCheckBox.Value) && ~(app.SobelxCheckBox.Value) && ~(app.SobelyCheckBox.Value) && ~(app.PrewittxCheckBox.Value) && ~(app.PrewittyCheckBox.Value) && ~(app.RobertsxCheckBox.Value) && ~(app.RobertsyCheckBox.Value) && ~(app.KirschCheckBox.Value) && ~(app.EdgeDetectionCheckBox.Value)
                if ~(app.multiply_active)
               % Display only Gaussian blur
                   if ~(app.DifferenceofGaussiansCheckBox.Value)
                       delete(app.UIAxesSC)
                       app.UIAxesSC_status = 0;
                       imshow(app.frame_g2, 'parent', app.UIAxes);  % display the blurred image
                       app.clear_labels()
                   else
                       app.frame_filter_dog = app.frame_g2dog - app.frame_g2;
                       if app.BinaryImageCheckBox.Value
                            delete(app.UIAxesSC)
                            app.UIAxesSC_status = 0;
                            imshow(app.frame_filter_dog, 'parent', app.UIAxes);  % display the blurred image
                            app.clear_labels()
                       else
                           app.restore_UIAxesSC();
                           imagesc(app.frame_filter_dog, 'parent', app.UIAxesSC);  % display the filtered image
                           app.clear_labels()
                       end
                    end
               end
               
           elseif app.SobelxCheckBox.Value && app.SobelyCheckBox.Value && ~(app.StandardCheckBox.Value)  && ~(app.PrewittxCheckBox.Value) && ~(app.PrewittyCheckBox.Value) && ~(app.KirschCheckBox.Value) && ~(app.RobertsxCheckBox.Value) && ~(app.RobertsyCheckBox.Value)
               % Generate only Sobel image
               im_sobelxy = (abs(im_sobelx) + abs(im_sobely)); % combine x and y image elements
               app.frame_current = im_sobelxy;
%                if app.multiply_active || app.rotation_active
%                    app.frame_current = conv2(app.frame_current,k);
%                end
               app.sc_req = 1;
           elseif app.PrewittxCheckBox.Value && app.PrewittyCheckBox.Value && ~(app.StandardCheckBox.Value)  && ~(app.SobelxCheckBox.Value) && ~(app.SobelyCheckBox.Value) && ~(app.KirschCheckBox.Value) && ~(app.RobertsxCheckBox.Value) && ~(app.RobertsyCheckBox.Value)
               % Generate only Prewitt image
               im_prewittxy = (abs(im_prewittx) + abs(im_prewitty)); % combine x and y image elements
               app.frame_current = im_prewittxy;
               app.sc_req = 1;
            elseif app.RobertsxCheckBox.Value && app.RobertsyCheckBox.Value && ~(app.PrewittxCheckBox.Value) && ~(app.PrewittyCheckBox.Value) && ~(app.StandardCheckBox.Value)  && ~(app.SobelxCheckBox.Value) && ~(app.SobelyCheckBox.Value) && ~(app.KirschCheckBox.Value)
               % Generate only Roberts image
               im_robertsxy = (abs(im_robertsx) + abs(im_robertsy)); % combine x and y image elements
               app.frame_current = im_robertsxy;
               app.sc_req = 1;
            elseif app.KirschCheckBox.Value  && ~(app.StandardCheckBox.Value)  && ~(app.SobelxCheckBox.Value) && ~(app.SobelyCheckBox.Value) && ~(app.PrewittyCheckBox.Value) && ~(app.PrewittxCheckBox.Value) && ~(app.RobertsxCheckBox.Value) && ~(app.RobertsyCheckBox.Value)
               % Generate only Kirsch image
               im_kirsch = conv2(app.frame_g2, k_kirsch, 'same');
               app.frame_current = im_kirsch;
               app.sc_req =1;

            elseif app.StandardCheckBox.Value || app.SobelxCheckBox.Value || app.SobelyCheckBox.Value || app.PrewittxCheckBox.Value || app.PrewittyCheckBox.Value || app.KirschCheckBox.Value || app.RobertsxCheckBox.Value || app.RobertsyCheckBox.Value
               % Display Combined Filter 
               app.sc_req = 1;
     
            elseif ~(app.UIAxesSC_status) && ~(app.DetectHarrisFeaturesCheckBox.Value) && ~(app.DetectSURFFeaturesCheckBox.Value)
               % no filters selected   
               delete(app.UIAxesSC)
               app.UIAxesSC_status = 0;
               imshow(app.frame, 'parent', app.UIAxes);  % display the original image image
           end % end if
           
           if (app.EdgeDetectionCheckBox.Value)
               app.edge_detect();
           elseif app.DetectHarrisFeaturesCheckBox.Value || app.DetectSURFFeaturesCheckBox.Value
               app.features();    
           % Display Scaled Colour filters
           elseif app.sc_req == 1
               app.restore_UIAxesSC();
               imagesc(app.frame_current, 'parent', app.UIAxesSC);  % display the filtered image
               app.clear_labels()
               app.sc_req = 0;
               app.multiply_active = 0;
           end % end if
           app.k_current = k;  % storage of current used k value
        end % end select_filter()
        
        
        function restore_UIAxesSC(app)
            if app.UIAxesSC_status == 0
                app.UIAxesSC = uiaxes(app.UIFigure);
                title(app.UIAxesSC, '')
                xlabel(app.UIAxesSC, '')
                ylabel(app.UIAxesSC, '')
                app.UIAxesSC.Position = [490 187 676 564];
                app.UIAxesSC_status = 1;
            end
        end

        function features(app)
              delete(app.UIAxesSC)
              app.UIAxesSC_status = 0;
              if app.GaussianBlur_1CheckBox.Value
                 imshow(app.frame_g2,'parent',app.UIAxes)
              else
                 imshow(app.frame,'parent',app.UIAxes)
              end
              hold(app.UIAxes,"on");
%             if app.UIAxesSC_status
                if app.DetectHarrisFeaturesCheckBox.Value
                    corners1 = detectHarrisFeatures(app.frame_g2, 'MinQuality', app.MinimumQualitySlider.Value);                     
                    corners1 = corners1.selectStrongest(round(app.AmountofFeaturesSlider.Value));
                    plot(app.UIAxes, corners1.Location(:,1), corners1.Location(:,2), 'x','Markersize',15 ,'LineStyle',"none", "Color",'red');
                end
                if app.DetectSURFFeaturesCheckBox.Value
                    corners2 = detectSURFFeatures(app.frame_g2);
                    corners2 = corners2.selectStrongest(round(app.AmountofFeaturesSlider.Value));
                    plot(app.UIAxes, corners2.Location(:,1), corners2.Location(:,2), 'o','Markersize',20 ,'LineStyle',"none",'Color', 'green');
                end
                hold(app.UIAxes,"off");
%             else % plot on normal
%                 delete(app.UIAxesSC);
%                 app.UIAxesSC_status = 0;
%                 if app.DetectHarrisFeaturesCheckBox.Value
%                     corners = detectHarrisFeatures(app.frame_current, 'MinQuality', app.MinimumQualitySlider.Value);
%                     corners = corners.selectStrongest(app.AmountofFeaturesSlider.Value);
%                     imagesc(app.frame_current,'parent',app.UIAxesSC)
%                     hold(app.UIAxes,"on");
%                     plot(app.UIAxes, corners.Location(:,1), corners.Location(:,2), 'x','Markersize',10 ,'LineStyle',"none");
%                     hold(app.UIAxes,"off")
%                 elseif app.DetectSURFFeaturesCheckBox.Value
%                     corners = detectSURFFeatures(app.frame_current);
%                     corners = corners.selectStrongest(app.AmountofFeaturesSlider.Value);
%                     imshow(app.frame_current, 'parent', app.UIAxes);
%                     hold(app.UIAxes,"on");
%                     plot(app.UIAxes, corners);
%                     hold(app.UIAxes,"off");
%                 end 
%             end

        end 

        
        function edge_detect(app)
            if app.SobelButton.Value
                im_edge = edge(app.frame_g2,'Sobel',app.EdgeThresholdSlider.Value);
            elseif app.PrewittButton.Value
                im_edge = edge(app.frame_g2,'Prewitt',app.EdgeThresholdSlider.Value);
            elseif app.RobertsButton.Value
                im_edge = edge(app.frame_g2,'Roberts',app.EdgeThresholdSlider.Value);
            end
                delete(app.UIAxesSC);
                app.UIAxesSC_status = 0;
                imshow(im_edge, 'parent', app.UIAxes);
        end
 
        
        function k_out = rotate_k(app, k_in)
            rot_total = rotx(app.xaxisEditField.Value) * roty(app.yaxisEditField.Value) * rotz(app.zaxisEditField.Value);
            [app.xaxisEditField.Value, app.yaxisEditField.Value, app.zaxisEditField.Value] = deal(0);
            k_out = k_in * rot_total;
        end
 

        % Read the current values in the user input kernel and assign in to
        % k_out
        function k_out = read_k(app) 
            k_out = [app.kernel_a1.Value, app.kernel_a2.Value, app.kernel_a3.Value;
                     app.kernel_b1.Value, app.kernel_b2.Value, app.kernel_b3.Value;
                     app.kernel_c1.Value, app.kernel_c2.Value, app.kernel_c3.Value];
        end % end read_k
   
        
        % assingns the values inside the input k_in kernel array onto the
        % kernel display
        function disp_k(app, k_in) 
             app.kernel_a1.Value = k_in(1,1);
             app.kernel_a2.Value = k_in(1,2);
             app.kernel_a3.Value = k_in(1,3);
             app.kernel_b1.Value = k_in(2,1);
             app.kernel_b2.Value = k_in(2,2);
             app.kernel_b3.Value = k_in(2,3);
             app.kernel_c1.Value = k_in(3,1);
             app.kernel_c2.Value = k_in(3,2);
             app.kernel_c3.Value = k_in(3,3);
        end
  
        
        function clear_labels(app)
           title(app.UIAxes,"");
           xlabel(app.UIAxes,"");
           ylabel(app.UIAxes,"");
%            title(app.UIAxesSC,"");
%            xlabel(app.UIAxesSC,"");
%            ylabel(app.UIAxesSC,"");
        end
    end % end private methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.webcams = webcamlist;
            if app.webcams(1) == ""
                app.webcams(1) = 'No webcams detected';
            end
            app.CameraSelectListBox = uilistbox(app.UIFigure);
            for i = 1:size(app.webcams)
                app.CameraSelectListBox.Items{i} = app.webcams{i};
            end
            app.CameraSelectListBox.Value = app.webcams(1);
            app.CameraSelectListBox.Position = [831 74 141 94];
        end

        % Button pushed function: ActivateWebcamButton
        function ActivateWebcamButtonPushed(app, event)
         app.UseOwnimagevideoCheckBox.Value = 0;
         app.UseSampleImageCheckBox.Value = 0;
         app.image_on = 0;
            clear cam  % ensures webcam is available
            pause(0.5)
            cam = webcam(find(strcmp(app.webcams, app.CameraSelectListBox.Value))); % select webcam
            app.image_on = 1;
            while app.image_on == 1  % continue recording snapshot until the app.image_on value is changed externally
                app.k_user = app.read_k();
                app.frame = snapshot(cam);
                app.frame_g = rgb2gray(app.frame);  % convert each frame of the webcam into greyscale
                app.frame_current = app.frame_g;
                app.frame_g2 = app.frame_g;
                app.check_settings()
                pause(0.05);  % allow time for the application to parse for any inputs made by the user during the while loop
                drawnow();
            end
           clear cam
        end

        % Button pushed function: StopWebcamButton
        function StopWebcamButtonPushed(app, event)
            app.image_on = 0;  % when the stop button is pushed, 
                               % the status variable is changed to
                               % interrupt all the currently active image
                               % capturing while loops
        end

        % Button pushed function: OpenownimagefileButton
        function OpenownimagefileButtonPushed(app, event)
            app.UseSampleImageCheckBox.Value = 0;
            app.UseOwnimagevideoCheckBox.Value = 1;
            while app.UseOwnimagevideoCheckBox.Value
                fullFileName = uigetfile('*.jpg');  % opens 
                app.frame = imread(fullFileName);
                app.frame_g = rgb2gray(app.frame);
                app.image_on = 1;
                app.frame_current = app.frame_g;
                app.frame_g2 = app.frame_g;
                while app.image_on == 1
                    app.k_user = app.read_k();
                    app.check_settings(); % check for change in any of the settings
                    pause(0.5);     % allow time for the application to parse for any inputs made by the user during the while loop
                    drawnow();
                    figure(app.UIFigure);  % display the application window in case it minimizes
                end
            end
        end

        % Button pushed function: ResetKernelButton
        function ResetKernelButtonPushed(app, event)
            [app.kernel_a1.Value, app.kernel_a2.Value, app.kernel_a3.Value] = deal(0);  % deals 0 along all the user kernel input windows
            [app.kernel_b1.Value, app.kernel_b3.Value] = deal(0);
            [app.kernel_c1.Value, app.kernel_c2.Value, app.kernel_c3.Value] = deal(0);
            app.kernel_b2.Value = 1;
            app.UserInputCheckBox.Value = 1;
            % Set all filter check boxes to 0
            app.StandardCheckBox.Value = 0; 
            app.GaussianBlur_1CheckBox.Value = 0; 
            app.SobelyCheckBox.Value = 0;
            app.SobelxCheckBox.Value = 0;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.UserInputCheckBox.Value = 1;
            app.k_mult = [app.kernel_a1_2.Value, app.kernel_a2_2.Value, app.kernel_a3_2.Value;
                          app.kernel_b1_2.Value, app.kernel_b2_2.Value, app.kernel_b3_2.Value;
                          app.kernel_c1_2.Value, app.kernel_c2_2.Value, app.kernel_c3_2.Value];      
            app.multiply_active = 1;
        end

        % Button pushed function: RotateKernelButton
        function RotateKernelButtonPushed(app, event)
            app.k_rot = rotx(app.xaxisEditField.Value) * roty(app.yaxisEditField.Value) * rotz(app.zaxisEditField.Value);
            app.UserInputCheckBox.Value = 1;
            app.rotation_active = 1;
        end

        % Value changed function: DetectSURFFeaturesCheckBox
        function DetectSURFFeaturesCheckBoxValueChanged(app, event)
%             app.DetectHarrisFeaturesCheckBox.Value = 0; % Ensures the other features select is switched off
            app.EdgeDetectionCheckBox.Value = 0;
        end

        % Value changed function: DetectHarrisFeaturesCheckBox
        function DetectHarrisFeaturesCheckBoxValueChanged(app, event)
%             app.DetectSURFFeaturesCheckBox.Value = 0; % Ensures the other features select is switched off
            app.EdgeDetectionCheckBox.Value = 0;
        end

        % Value changed function: DifferenceofGaussiansCheckBox
        function DifferenceofGaussiansCheckBoxValueChanged(app, event)
            if app.DifferenceofGaussiansCheckBox.Value
%                 app.GaussianBlur_1CheckBox.Value = 1;
            end
        end

        % Value changed function: BinaryImageCheckBox
        function BinaryImageCheckBoxValueChanged(app, event)
            if app.BinaryImageCheckBox.Value
%                 app.GaussianBlur_1CheckBox.Value = 1;
%                 app.DifferenceofGaussiansCheckBox.Value = 1;
            end
        end

        % Value changed function: UseSampleImageCheckBox
        function UseSampleImageCheckBoxValueChanged(app, event)
                app.UseOwnimagevideoCheckBox.Value = 0;
                while app.UseSampleImageCheckBox.Value
                     switch app.DropDown.Value
                         case 'Cameraman.tif'
                             app.frame = imread('cameraman.tif');
                             app.frame_g = app.frame;
                         case 'Rice.png'
                             app.frame = imread('rice.png');
                             app.frame_g = app.frame;
                         case 'Street1.jpg'
                             app.frame = imread('street1.jpg');
                             app.frame_g = rgb2gray(app.frame);
                         case 'Street2.jpg'
                             app.frame = imread('street2.jpg');
                             app.frame_g = rgb2gray(app.frame);
                     end
                    app.image_on = 1;
                    app.frame_current = app.frame_g;
                    app.frame_g2 = app.frame_g;
                    while app.image_on == 1
                        app.k_user = app.read_k();
                        app.check_settings(); % check for change in any of the settings
                        pause(0.5);     % allow time for the application to parse for any inputs made by the user during the while loop
                        drawnow();
                            figure(app.UIFigure);  % display the application window in case it minimizes
                    end % end while
                end % end while 
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.UseSampleImageCheckBoxValueChanged()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ActivateWebcamButton = uibutton(app.UIFigure, 'push');
            app.ActivateWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateWebcamButtonPushed, true);
            app.ActivateWebcamButton.Text = 'Activate Webcam';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StopWebcamButton = uibutton(app.UIFigure, 'push');
            app.StopWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @StopWebcamButtonPushed, true);
            app.StopWebcamButton.Text = 'Stop Webcam';
            app.OpenownimagefileButton = uibutton(app.UIFigure, 'push');
            app.OpenownimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenownimagefileButtonPushed, true);
            app.OpenownimagefileButton.Text = 'Open own image file';
            app.UIAxesSC = uiaxes(app.UIFigure);
            title(app.UIAxesSC, '')
            xlabel(app.UIAxesSC, '')
            ylabel(app.UIAxesSC, '')
            app.Panel = uipanel(app.UIFigure);
            app.GaussianFilterSettingsPanel = uipanel(app.Panel);
            app.GaussianFilterSettingsPanel.Title = 'Gaussian Filter Settings';
            app.GaussianBlur_1CheckBox = uicheckbox(app.GaussianFilterSettingsPanel);
            app.GaussianBlur_1CheckBox.Text = 'Gaussian Blur (σ_1)';
            app.Gaussianstandarddeviation_1SliderLabel = uilabel(app.GaussianFilterSettingsPanel);
            app.Gaussianstandarddeviation_1SliderLabel.Text = {'Gaussian'; 'standard'; 'deviation (σ_1)'};
            app.Gaussianstandarddeviation_1Slider = uislider(app.GaussianFilterSettingsPanel);
            app.Gaussianstandarddeviation_1Slider.Limits = [0.1 10];
            app.Gaussianstandarddeviation_1Slider.Value = 2;
            app.DifferenceofGaussiansCheckBox = uicheckbox(app.GaussianFilterSettingsPanel);
            app.DifferenceofGaussiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferenceofGaussiansCheckBoxValueChanged, true);
            app.DifferenceofGaussiansCheckBox.Text = 'Difference of Gaussians';
            app.BinaryImageCheckBox = uicheckbox(app.GaussianFilterSettingsPanel);
            app.BinaryImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinaryImageCheckBoxValueChanged, true);
            app.BinaryImageCheckBox.Text = 'Binary Image';
            app.Gaussianstandarddeviation_2SliderLabel = uilabel(app.GaussianFilterSettingsPanel);
            app.Gaussianstandarddeviation_2SliderLabel.Text = {'Gaussian'; 'standard'; 'deviation (σ_2)'};
            app.Gaussianstandarddeviation_2Slider = uislider(app.GaussianFilterSettingsPanel);
            app.Gaussianstandarddeviation_2Slider.Limits = [0.1 10];
            app.Gaussianstandarddeviation_2Slider.Value = 3;
            app.GaussWindowSliderLabel = uilabel(app.GaussianFilterSettingsPanel);
            app.GaussWindowSliderLabel.Text = {'Gauss'; 'Window'};
            app.GaussWindowSlider = uislider(app.GaussianFilterSettingsPanel);
            app.GaussWindowSlider.Limits = [2 10];
            app.GaussWindowSlider.Value = 2;
            app.FilterSelectPanel = uipanel(app.Panel);
            app.FilterSelectPanel.Title = 'Filter Select';
            app.PrewittFilterPanel = uipanel(app.FilterSelectPanel);
            app.PrewittFilterPanel.Title = 'Prewitt Filter';
            app.PrewittyCheckBox = uicheckbox(app.PrewittFilterPanel);
            app.PrewittyCheckBox.Text = 'Prewitt y';
            app.PrewittxCheckBox = uicheckbox(app.PrewittFilterPanel);
            app.PrewittxCheckBox.Text = 'Prewitt x';
            app.SobelFilterPanel = uipanel(app.FilterSelectPanel);
            app.SobelFilterPanel.Title = 'Sobel Filter';
            app.SobelyCheckBox = uicheckbox(app.SobelFilterPanel);
            app.SobelyCheckBox.Text = 'Sobel y';
            app.SobelxCheckBox = uicheckbox(app.SobelFilterPanel);
            app.SobelxCheckBox.Text = 'Sobel x';
            app.OtherKernelsPanel = uipanel(app.FilterSelectPanel);
            app.OtherKernelsPanel.Title = 'Other Kernels';
            app.KirschCheckBox = uicheckbox(app.OtherKernelsPanel);
            app.KirschCheckBox.Text = 'Kirsch';
            app.StandardCheckBox = uicheckbox(app.OtherKernelsPanel);
            app.StandardCheckBox.Text = 'Standard';
            app.RobertsFilterPanel = uipanel(app.FilterSelectPanel);
            app.RobertsFilterPanel.Title = 'Roberts Filter';
            app.RobertsxCheckBox = uicheckbox(app.RobertsFilterPanel);
            app.RobertsxCheckBox.Text = 'Roberts x';
            app.RobertsyCheckBox = uicheckbox(app.RobertsFilterPanel);
            app.RobertsyCheckBox.Text = 'Roberts y';
            app.EdgeDetectionSettingsPanel = uipanel(app.Panel);
            app.EdgeDetectionSettingsPanel.Title = 'Edge Detection Settings';
            app.EdgeDetectionCheckBox = uicheckbox(app.EdgeDetectionSettingsPanel);
            app.EdgeDetectionCheckBox.Text = 'EdgeDetection';
            app.EdgeThresholdSliderLabel = uilabel(app.EdgeDetectionSettingsPanel);
            app.EdgeThresholdSliderLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.EdgeDetectionSettingsPanel);
            app.EdgeThresholdSlider.Limits = [0 0.7];
            app.EdgeThresholdSlider.Value = 0.2;
            app.ButtonGroup = uibuttongroup(app.EdgeDetectionSettingsPanel);
            app.SobelButton = uiradiobutton(app.ButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.SobelButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uiradiobutton(app.ButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.DetectFeaturesPanel = uipanel(app.Panel);
            app.DetectFeaturesPanel.Title = 'Detect Features';
            app.DetectHarrisFeaturesCheckBox = uicheckbox(app.DetectFeaturesPanel);
            app.DetectHarrisFeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectHarrisFeaturesCheckBoxValueChanged, true);
            app.DetectHarrisFeaturesCheckBox.Text = 'Detect Harris Features';
            app.AmountofFeaturesSliderLabel = uilabel(app.DetectFeaturesPanel);
            app.AmountofFeaturesSliderLabel.Text = 'Amount of Features';
            app.AmountofFeaturesSlider = uislider(app.DetectFeaturesPanel);
            app.AmountofFeaturesSlider.Limits = [1 200];
            app.AmountofFeaturesSlider.Value = 50;
            app.MinimumQualitySliderLabel = uilabel(app.DetectFeaturesPanel);
            app.MinimumQualitySliderLabel.Text = 'Minimum Quality';
            app.MinimumQualitySlider = uislider(app.DetectFeaturesPanel);
            app.MinimumQualitySlider.Limits = [0.01 1];
            app.MinimumQualitySlider.Value = 0.01;
            app.DetectSURFFeaturesCheckBox = uicheckbox(app.DetectFeaturesPanel);
            app.DetectSURFFeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectSURFFeaturesCheckBoxValueChanged, true);
            app.DetectSURFFeaturesCheckBox.Text = 'Detect SURF Features';
            app.StopbeforeswitchingCamerasLabel = uilabel(app.UIFigure);
            app.StopbeforeswitchingCamerasLabel.Text = 'Stop before switching Cameras';
            app.CurrentConvolutionKernelPanel = uipanel(app.UIFigure);
            app.CurrentConvolutionKernelPanel.Title = 'Current Convolution Kernel';
            app.kernel_a1 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_a2 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_a3 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_b1 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_b2 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_b3 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_c1 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_c2 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.kernel_c3 = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.KernelDisplayeditableLabel = uilabel(app.CurrentConvolutionKernelPanel);
            app.KernelDisplayeditableLabel.Text = 'Kernel Display (editable)';
            app.ResetKernelButton = uibutton(app.CurrentConvolutionKernelPanel, 'push');
            app.ResetKernelButton.ButtonPushedFcn = createCallbackFcn(app, @ResetKernelButtonPushed, true);
            app.ResetKernelButton.Text = 'Reset Kernel';
            app.KernelrotationdegreesLabel = uilabel(app.CurrentConvolutionKernelPanel);
            app.KernelrotationdegreesLabel.Text = 'Kernel rotation (degrees)';
            app.xaxisEditFieldLabel = uilabel(app.CurrentConvolutionKernelPanel);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.xaxisEditField = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.yaxisEditFieldLabel = uilabel(app.CurrentConvolutionKernelPanel);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.yaxisEditField = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.zaxisEditFieldLabel = uilabel(app.CurrentConvolutionKernelPanel);
            app.zaxisEditFieldLabel.Text = 'z-axis';
            app.zaxisEditField = uieditfield(app.CurrentConvolutionKernelPanel, 'numeric');
            app.UserInputCheckBox = uicheckbox(app.CurrentConvolutionKernelPanel);
            app.UserInputCheckBox.Text = 'User Input';
            app.RotateKernelButton = uibutton(app.CurrentConvolutionKernelPanel, 'push');
            app.RotateKernelButton.ButtonPushedFcn = createCallbackFcn(app, @RotateKernelButtonPushed, true);
            app.RotateKernelButton.Text = 'Rotate Kernel';
            app.ApplyontopofcurrentkernelPanel = uipanel(app.UIFigure);
            app.ApplyontopofcurrentkernelPanel.Title = 'Apply on top of current kernel';
            app.Label_2 = uilabel(app.ApplyontopofcurrentkernelPanel);
            app.Label_2.Text = '*';
            app.Label = uilabel(app.ApplyontopofcurrentkernelPanel);
            app.Label.Text = '[   ]';
            app.kernel_a1_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_a2_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_a3_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_b1_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_b2_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_b3_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_c1_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_c2_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.kernel_c3_2 = uieditfield(app.ApplyontopofcurrentkernelPanel, 'numeric');
            app.ApplyButton = uibutton(app.ApplyontopofcurrentkernelPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CameraSelectListBoxLabel = uilabel(app.UIFigure);
            app.CameraSelectListBoxLabel.Text = 'Camera Select';
            app.CameraSelectListBox = uilistbox(app.UIFigure);
            app.FilterSwitchButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterSwitchButtonGroup.Title = 'Filter Switch';
            app.OnButton = uiradiobutton(app.FilterSwitchButtonGroup);
            app.OnButton.Text = 'On';
            app.OffButton = uiradiobutton(app.FilterSwitchButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Cameraman.tif', 'Rice.png', 'Street1.jpg', 'Street2.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Cameraman.tif';
            app.UseSampleImageCheckBox = uicheckbox(app.UIFigure);
            app.UseSampleImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSampleImageCheckBoxValueChanged, true);
            app.UseSampleImageCheckBox.Text = 'Use Sample Image';
            app.UseOwnimagevideoCheckBox = uicheckbox(app.UIFigure);
            app.UseOwnimagevideoCheckBox.Text = 'Use Own image/video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES3H3_Lab1_Assessment_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end