classdef scratchobard_gaussian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ShowButton         matlab.ui.control.Button
        sigma1SliderLabel  matlab.ui.control.Label
        sigma1Slider       matlab.ui.control.Slider
        sigma2SliderLabel  matlab.ui.control.Label
        sigma2Slider       matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)

                im = imread('street1.jpg');
                im_g = rgb2gray(im);
                % figure; imshow(im_g)
                % K = [0 0 0; 1 0 -1; 0 0 0];  % convolution kernel
                % K_hor = [1 0 -1; 2 0 -2; 1 0 -1];    % Horziontal Sobel kernel
                % K_ver = [1 2 1; 0 0 0; -1 -2 -1];    % Vertical Sobel kernel
                
                
                % blur1 = imfilter(img, gauss1, 'replicate', 'same');
                % blur2 = imfilter(img, gauss2, 'replicate', 'same');
                % dog2 = blur1 - blur2;
                
                
                K_g1 = fspecial('gaussian', 5, app.sigma1Slider.Value);
                K_g2 = fspecial('gaussian', 5, app.sigma2Slider.Value);
                % K_g = K_g1 - K_g2;  % bandpass filter (???)
                imfiltered_g1 = conv2(im_g, K_g1);
%                 imfiltered_g2 = conv2(im_g, K_g2);
%                 imfiltered_g3 = imfiltered_g1 - imfiltered_g2;
                imagesc(imfiltered_g1, 'parent', app.UIAxes)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.sigma1SliderLabel = uilabel(app.UIFigure);
            app.sigma1SliderLabel.Text = 'sigma 1';
            app.sigma1Slider = uislider(app.UIFigure);
            app.sigma1Slider.Limits = [0.1 10];
            app.sigma1Slider.Value = 0.1;
            app.sigma2SliderLabel = uilabel(app.UIFigure);
            app.sigma2SliderLabel.Text = 'sigma 2';
            app.sigma2Slider = uislider(app.UIFigure);
            app.sigma2Slider.Limits = [0.1 10];
            app.sigma2Slider.Value = 0.1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scratchobard_gaussian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end